classdef covid_19 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID_19datavisualizationUIFigure  matlab.ui.Figure
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        ComulativeButton           matlab.ui.control.RadioButton
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        DeathesButton              matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        StateorRegionListBox       matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        AvarageofdaysSlider        matlab.ui.control.Slider
        AvarageofdaysSliderLabel   matlab.ui.control.Label
        CountryListBoxLabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dates %dates of recordes
        countries country  %array of country objects
        countries_names  string %array of country names
        covid_data
        com_deathes
        com_cases
    end
    
    methods (Access = private)
        
        function render(app)
            yyaxis(app.UIAxes,'left')
            cla(app.UIAxes);
            axis(app.UIAxes,[0 , Inf , 0 ,inf]);
            yyaxis(app.UIAxes,'right')
            cla(app.UIAxes);
            axis(app.UIAxes,[0 , Inf , 0 ,inf]);
            
            selected_state = app.StateorRegionListBox.Value;
            selected_country = app.CountryListBox.Value;
            curves = app.DatatoPlotButtonGroup.SelectedObject;
            option = app.OptionButtonGroup.SelectedObject;
            avg = app.AvarageofdaysSlider.Value;
            avg_txt = sprintf("%2.0f",avg);
            if(option == app.ComulativeButton)
                title = 'Comulative';
            else
                title = 'Daily';
            end
            if(curves == app.CasesButton)
                title = append(title,' Cases');
            elseif (curves == app.DeathesButton)
                title = append(title,' Deathes');
            else
                title = append(title,' Cases and Deathes');
            end
            title = append(title, ' for ',selected_state.name,' ',selected_country.name,' (',avg_txt,'-days mean)');
            app.UIAxes.Title.String = title;
            if(curves~=app.DeathesButton)
                yyaxis(app.UIAxes,'left')
                if selected_country.index == 1
                    if(option == app.ComulativeButton)
                        app.plot_global_cases_com();
                    else
                        app.plot_global_cases();
                    end
                else 
                    if(option == app.ComulativeButton)
                        selected_state.plot_cases_com(app.UIAxes,app.covid_data,avg);
                    else
                        selected_state.plot_cases(app.UIAxes,app.covid_data,avg);
                    end
                end
                
                
            end
            if(curves ~=app.CasesButton)
                yyaxis(app.UIAxes,'right')
                if selected_country.index == 1
                    if(option == app.ComulativeButton)
                        app.plot_global_deathes_com();
                    else
                        app.plot_global_deathes();
                    end
                else 
                    if(option == app.ComulativeButton)
                        selected_state.plot_deathes_com(app.UIAxes,app.covid_data,avg);
                    else
                        selected_state.plot_deathes(app.UIAxes,app.covid_data,avg);
                    end
                end
            end
        end
        
        function plot_global_deathes_com(app)
            app.com_deathes= movmean(app.com_deathes,app.AvarageofdaysSlider.Value);
            plot(app.UIAxes,app.com_deathes,'r');
        end
        function plot_global_deathes(app)
            deathes = zeros(1,size(app.com_deathes,2));
            for i = 2:size(app.com_deathes,2)
                deathes(i) = app.com_deathes(i) - app.com_deathes(i-1);
            end
            deathes= movmean(deathes,app.AvarageofdaysSlider.Value);
            plot(app.UIAxes,deathes,'r');
        end
        function plot_global_cases_com(app)
            app.com_cases= movmean(app.com_cases,app.AvarageofdaysSlider.Value);
            bar(app.UIAxes,app.com_cases,'b');
        end
        function plot_global_cases(app)
            cases = zeros(1,size(app.com_cases,2));
            for i = 2:size(app.com_cases,2)
                cases(i) = app.com_cases(i) - app.com_cases(i-1);
                
            end
            cases= movmean(cases,app.AvarageofdaysSlider.Value);
            bar(app.UIAxes,cases,'b');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            load covid_data.mat covid_data;
            app.covid_data = covid_data;
            app.dates = app.covid_data(1,3:end);
            xticklabels(app.UIAxes,app.dates);
            app.UIAxes.XTickLabelRotation = 30;
            
            app.com_cases = zeros(1,size(app.covid_data,2)-2);
            app.com_deathes = zeros(1,size(app.covid_data,2)-2);
            for i = 3:size(app.covid_data,2)-2
                for j = 2:size(app.covid_data,1)-1
                    a = app.covid_data{j,i};
                    app.com_deathes(i) = app.com_deathes(i) + a(2);
                    app.com_cases(i) = app.com_cases(i) + a(1);
                end
            end
            
            
            app.countries(1) = country('global',1); 
            app.countries_names(1) = 'global';
            app.countries(1).states(1)=state('all',1);
            app.countries(1).states_names(1)='all';
            for i = 2:size(app.covid_data,1)
                
                if(~strcmp(app.covid_data{i,1},app.covid_data{i-1,1}))
                    app.countries(end+1) = country(app.covid_data{i,1},i); 
                    app.countries_names(end+1) = app.covid_data{i,1};
                end
                if strcmp(app.covid_data{i,2},'')
                    app.countries(end).states(end+1)=state('all',i);
                    app.countries(end).states_names(end+1)='all';
                else
                    app.countries(end).states(end+1)=state(app.covid_data{i,2},i);
                    app.countries(end).states_names(end+1)=app.covid_data{i,2};
                end
                
                
            end
            app.CountryListBox.Items=[app.countries_names];
            app.CountryListBox.ItemsData = app.countries;
            app.StateorRegionListBox.Items = app.countries(1).states_names;
            app.StateorRegionListBox.ItemsData = app.countries(1).states;
            app.render();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            value = app.CountryListBox.Value;
            app.StateorRegionListBox.Items = value.states_names;
            app.StateorRegionListBox.ItemsData = value.states;
            app.render();
            
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.render();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.render();
            
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.render();
            
        end

        % Value changed function: AvarageofdaysSlider
        function AvarageofdaysSliderValueChanged(app, event)
            value = int8(app.AvarageofdaysSlider.Value);
            app.AvarageofdaysSlider.Value = double(value);
            app.render();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID_19datavisualizationUIFigure = uifigure('Visible', 'off');
            app.COVID_19datavisualizationUIFigure.Name = 'COVID_19 data visualization ';
            app.UIAxes = uiaxes(app.COVID_19datavisualizationUIFigure);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.CountryListBoxLabel = uilabel(app.COVID_19datavisualizationUIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.AvarageofdaysSliderLabel = uilabel(app.COVID_19datavisualizationUIFigure);
            app.AvarageofdaysSliderLabel.Text = {'Avarage'; 'of days'};
            app.AvarageofdaysSlider = uislider(app.COVID_19datavisualizationUIFigure);
            app.AvarageofdaysSlider.Limits = [1 15];
            app.AvarageofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AvarageofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AvarageofdaysSliderValueChanged, true);
            app.AvarageofdaysSlider.MinorTicks = [2 4 6 8 10 12 14];
            app.AvarageofdaysSlider.Value = 1;
            app.CountryListBox = uilistbox(app.COVID_19datavisualizationUIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionListBoxLabel = uilabel(app.COVID_19datavisualizationUIFigure);
            app.StateorRegionListBoxLabel.Text = {'State or'; ' Region:'};
            app.StateorRegionListBox = uilistbox(app.COVID_19datavisualizationUIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.DatatoPlotButtonGroup = uibuttongroup(app.COVID_19datavisualizationUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathesButton.Text = 'Deathes';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.COVID_19datavisualizationUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.ComulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.ComulativeButton.Text = 'Comulative';
            app.ComulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            % Show the figure after all components are created
            app.COVID_19datavisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid_19

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID_19datavisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID_19datavisualizationUIFigure)
        end
    end
end